classdef HelicopterParameterEstimation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        CalculateButton     matlab.ui.control.Button
        UITable             matlab.ui.control.Table
        NbEditField         matlab.ui.control.NumericEditField
        NbEditFieldLabel    matlab.ui.control.Label
        MTOWEditField       matlab.ui.control.NumericEditField
        MTOWEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: CalculateButton, UITable
        function CalculateButtonPushed(app, event)
g = 9.81;
rho = 1.225;
c = 0.18;

T = app.MTOWEditField.Value.*g;   % Thrust

%% Main Rotor
D = 0.977 * app.MTOWEditField.Value^(0.308);     % Diameter
R = D/2;                    % Radius
A = pi*R^2;                 % Disc Area

%% Tail Rotor
Dtr = 0.0886 * app.MTOWEditField.Value^(0.393);  % Diameter
Rtr = Dtr/2;                % Radius
Atr = pi*Rtr^2;             % Disc Area

%% Disc Loading
DL = 2.12 * (app.MTOWEditField.Value^(1/3) - 0.57) * g;  % From regression
%DL = app.MTOWEditField.Value*g/(pi*R^2)                 % Momentum theory

%% Total Power
Pto = 0.2928 * app.MTOWEditField.Value^(0.9043); % Total power at GTOW

%% Power Loading and Figure of Merit
% There are two equations for figure of merit, one is regression from 
% article, one is from momentum theory. When we arrange these together,
% figure of merit becomes:
FM = 10^(log10(T/(Pto*1000)) + 0.5*log10(DL)) / sqrt(2*rho);

%% Airframe
Lf = 0.824*D^(1.056);   % Fuselage length
Lrt = 1.09*D^(1.03);    % Overall length

%% Weight Prediction
We = 0.59*app.MTOWEditField.Value;
Wpl = 0.31*app.MTOWEditField.Value;

%% Maximum Speed
Vmax = 39.8*app.MTOWEditField.Value^(0.242);

%% Rate of Climb
Vc = 142*app.MTOWEditField.Value^(0.157);

%% Engine (EMRAX 188)
Psea = 37; % kW

%% Table
app.UITable.Data = {'Empty Weight (kg)', sprintf('%.1f', We);
    'Payload (kg)', sprintf('%.1f', Wpl);
    'Main Rotor Radius (m)', sprintf('%.2f', R);
    'Tail Rotor Radius (m)', sprintf('%.2f', Rtr);
    'Takeoff Total Power (kW)', sprintf('%.2f', Pto);
    'Fuselage Length (m)', sprintf('%.2f', Lf);
    'Airframe Length (m)', sprintf('%.2f', Lrt)};

%% ASSIGN-IN
assignin('base', 'We', We);
assignin('base', 'Wpl', Wpl);
assignin('base', 'R', R);
assignin('base', 'Rtr', Rtr);
assignin('base', 'Pto', Pto);
assignin('base', 'Lf', Lf);
assignin('base', 'Lrt', Lrt);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MTOWEditFieldLabel = uilabel(app.UIFigure);
            app.MTOWEditFieldLabel.Text = 'MTOW';
            app.MTOWEditField = uieditfield(app.UIFigure, 'numeric');
            app.NbEditFieldLabel = uilabel(app.UIFigure);
            app.NbEditFieldLabel.Text = 'Nb';
            app.NbEditField = uieditfield(app.UIFigure, 'numeric');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Parameter'; 'Value'};
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HelicopterParameterEstimation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end